classdef MeltElectroCoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Hyperlink2                    matlab.ui.control.Hyperlink
        Hyperlink                     matlab.ui.control.Hyperlink
        NomenclatureLabel_4           matlab.ui.control.Label
        MeltElectroCoderLabel         matlab.ui.control.Label
        GenerateButton                matlab.ui.control.Button
        PrintOptionsPanel             matlab.ui.container.Panel
        AddcommandstobeexecutedaftertheprintisfinishedLabel  matlab.ui.control.Label
        AddcommandstobeexecutedpriortotheprintstartLabel  matlab.ui.control.Label
        CommandsaddedTextArea_2       matlab.ui.control.TextArea
        PersonalisedGcodeCommandsEditFieldLabel_2  matlab.ui.control.Label
        CommandsaddedTextArea         matlab.ui.control.TextArea
        RotationResolutionEditFieldLabel  matlab.ui.control.Label
        RotationResolutionEditField   matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel      matlab.ui.control.Label
        DiametermmEditField           matlab.ui.control.NumericEditField
        MandrelPreferencesLabel       matlab.ui.control.Label
        CellDesignPanel               matlab.ui.container.Panel
        CellParameter2                matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        CellParameter1                matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        ChooseabuildforyourcellLabel  matlab.ui.control.Label
        ClicktoenlargeLabel           matlab.ui.control.Label
        BuildDropDownLabel            matlab.ui.control.Label
        ImageCellDesign               matlab.ui.control.Image
        BuildDropDown                 matlab.ui.control.DropDown
        ScaffoldInformationPanel      matlab.ui.container.Panel
        SpeedmmminEditField           matlab.ui.control.NumericEditField
        SpeedmmminEditFieldLabel      matlab.ui.control.Label
        TailmmSlider                  matlab.ui.control.Slider
        TailmmSliderLabel             matlab.ui.control.Label
        LayersEditField               matlab.ui.control.NumericEditField
        LayersEditFieldLabel          matlab.ui.control.Label
        LengthmmEditFieldLabel        matlab.ui.control.Label
        WidthmmEditField              matlab.ui.control.NumericEditField
        LengthmmEditField             matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel         matlab.ui.control.Label
        GeometryDropDown              matlab.ui.control.DropDown
        GeometryDropDownLabel         matlab.ui.control.Label
        CategoryDropDown              matlab.ui.control.DropDown
        CategoryDropDownLabel         matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        ExportGcodeButton             matlab.ui.control.Button
        CustomisationPanel            matlab.ui.container.Panel
        ClicktoenlargeLabel_2         matlab.ui.control.Label
        CorrImage                     matlab.ui.control.Image
        LayerstoShiftDropDown         matlab.ui.control.DropDown
        LayerstoShiftDropDownLabel    matlab.ui.control.Label
        LayerShiftingandOffsetOptionsLabel  matlab.ui.control.Label
        PhaseumEditField              matlab.ui.control.NumericEditField
        PhaseumEditFieldLabel         matlab.ui.control.Label
        AmplitudeumEditField          matlab.ui.control.NumericEditField
        AmplitudeumEditFieldLabel     matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        NodeCorrectionLabel           matlab.ui.control.Label
        FittosizeButton               matlab.ui.control.Button
        ScaletosizeButton             matlab.ui.control.Button
        NochangeButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        ScaleTab                      matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes1                       matlab.ui.control.UIAxes
        FittedTab                     matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        ImageUO                       matlab.ui.control.Image
        ResetButton                   matlab.ui.control.Button
        GeneratedFilesPanel           matlab.ui.container.Panel
        ClicktoenlargeLabel_3         matlab.ui.control.Label
        FilenameTextArea              matlab.ui.control.TextArea
        FilenameTextAreaLabel         matlab.ui.control.Label
        PreviewScaffoldLabel          matlab.ui.control.Label
        PreviewGcodeLabel             matlab.ui.control.Label
        GcodePreviewTextArea          matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        ImageQUT                      matlab.ui.control.Image
        ImageBTM                      matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        startCommands = ""; 
        numStartCommands = 0;
        endCommands = ""; 
        numEndCommands = 0;
        Generated_Information = 0;
        Generated_PreviewKP = 0; 
        Generated_PreviewPC = 0;
        geo_divp = 0; 
        geo_divq = 0; 
        geo_La = 0;
        Generated_File = 0; 
        pathDirectory = 0;
        saveDirectory = 0;
        pressedButton1 = false; 
        pressedButton2 = false; 
        pressedButton3 = false;

        % USER DEFINED SCAFFOLD PROPERTIES
        User_Length = 0; 
        User_Width = 0;  
        Layers = 0; 
        Tail = 0; 
        Speed = 0;
        Category = 0; 
        Geometry = 0; 
        Build = 0;    
        Diameter = 0; 
        Resolution = 0;        
        Correction = 0; 
        Mode = 0; 
        amp = 0; 
        pha = 0;  
        Param1 = 0; 
        Param2 = 0;

        % CHANGING PROPERTIES NEEDED FOR GCODE
        Length = 0; 
        Width = 0;
        p = 0; 
        q = 0; 
        K = 0; 
        P = 0; 
        A = 0; 
        B = 0;
        ucP = 0; 
        ucQ = 0;

        % GENERATED & PREVIEW IMAGES
        User_imgScaffold = 0;
        User_ax1 = 0; 
        User_ax2 = 0; 
        User_ax3 = 0; 
        User_ax4 = 0;
    end

    methods (Access = private)
        function resetSIpanel(app)
            app.ScaffoldInformationPanel.Visible = "on"; 
            app.ScaffoldInformationPanel.Enable = "on";
            app.CategoryDropDown.Enable = "on"; 
            app.CategoryDropDownLabel.Enable = "on"; 
            app.CategoryDropDown.Value = "Select";
            app.GeometryDropDown.Enable = "off"; 
            app.GeometryDropDownLabel.Enable = "off"; 
            app.GeometryDropDown.Value = "Select";
            app.LengthmmEditField.Enable = "off"; 
            app.LengthmmEditFieldLabel.Enable = "off"; 
            app.LengthmmEditField.Value = 0;
            app.WidthmmEditField.Enable = "off"; 
            app.WidthmmEditFieldLabel.Enable = "off"; 
            app.WidthmmEditField.Value = 0;
            app.LayersEditField.Enable = "off"; 
            app.LayersEditFieldLabel.Enable = "off"; 
            app.LayersEditField.Value = 0;
            app.TailmmSlider.Enable = "off"; 
            app.TailmmSliderLabel.Enable = "off"; 
            app.TailmmSlider.Value = 0.5;
            app.SpeedmmminEditField.Enable = "off"; 
            app.SpeedmmminEditFieldLabel.Enable = "off"; 
            app.SpeedmmminEditField.Value = 0;
        end
        function resetCDpanel(app)
            app.CellDesignPanel.Visible = "on"; 
            app.CellDesignPanel.Enable = "on";
            app.ChooseabuildforyourcellLabel.Enable = "off"; 
            app.ClicktoenlargeLabel.Enable = "off"; 
            app.ClicktoenlargeLabel.Visible = 'off';
            app.BuildDropDown.Enable = "off"; 
            app.BuildDropDownLabel.Enable = "off"; 
            app.BuildDropDown.Value = 'Select';
            app.CellParameter1.Enable = "off"; 
            app.CellParameter1.Value = 0; 
            app.Label.Enable = "off"; 
            app.Label.Visible = "off";
            app.CellParameter2.Enable = "off"; 
            app.CellParameter2.Value = 0; 
            app.Label_2.Enable = "off"; 
            app.Label_2.Visible = "off";
            app.ImageCellDesign.Enable = "off"; 
            app.ImageCellDesign.Visible = 'off';
        end
        function resetPOpanel(app)
            app.PrintOptionsPanel.Visible = "on"; 
            app.PrintOptionsPanel.Enable = "on"; 
            app.MandrelPreferencesLabel.Enable = 'off';
            app.DiametermmEditField.Enable = 'off'; 
            app.DiametermmEditFieldLabel.Enable = 'off'; 
            app.DiametermmEditField.Value = 0;
            app.RotationResolutionEditField.Enable = 'off'; 
            app.RotationResolutionEditFieldLabel.Enable = 'off'; 
            app.RotationResolutionEditField.Value = 0;
            app.CommandsaddedTextArea.Value = ""; 
            app.CommandsaddedTextArea_2.Value = "";
        end
        function resetCUpanel(app)
            app.CustomisationPanel.Visible = "on"; 
            app.CustomisationPanel.Enable = "on";
            app.NochangeButton.BackgroundColor = [0.5,0.5,0.5]; 
            app.ScaletosizeButton.BackgroundColor = [0.5,0.5,0.5]; 
            app.FittosizeButton.BackgroundColor = [0.5,0.5,0.5];
            app.NochangeButton.Enable = 'off'; 
            app.ScaletosizeButton.Enable = 'off'; 
            app.FittosizeButton.Enable = 'off';
            app.NodeCorrectionLabel.Enable = 'off'; 
            app.Switch.Enable = 'off'; 
            app.Switch.Value = "Off"; 
            app.LayerShiftingandOffsetOptionsLabel.Enable = 'off';
            app.LayerstoShiftDropDownLabel.Enable = 'off'; 
            app.LayerstoShiftDropDown.Enable = 'off'; 
            app.LayerstoShiftDropDown.Value = "Select";
            app.AmplitudeumEditField.Enable = 'off'; 
            app.AmplitudeumEditFieldLabel.Enable = 'off'; 
            app.AmplitudeumEditField.Value = 0;
            app.PhaseumEditField.Enable = 'off'; 
            app.PhaseumEditFieldLabel.Enable = 'off'; 
            app.PhaseumEditField.Value = 0;
            app.UITable.Data = []; 
            app.UITable.Enable = 'off'; 
            app.pressedButton1 = false; 
            app.pressedButton2 = false; 
            app.pressedButton3 = false;
            app.UIAxes1.Visible = 'off'; 
            app.UIAxes2.Visible = 'off'; 
            app.UIAxes3.Visible = 'off'; 
            app.UIAxes4.Visible = 'off'; 
            cla(app.UIAxes1); 
            cla(app.UIAxes2); 
            cla(app.UIAxes3); 
            cla(app.UIAxes4);
            app.CorrImage.Enable = 'off'; 
            app.CorrImage.Visible = 'off'; 
            app.ClicktoenlargeLabel_2.Enable = 'off'; 
            app.ClicktoenlargeLabel_2.Visible = 'off';
            app.ExportGcodeButton.Enable = 'off';
        end
        function resetGFpanel(app)
            app.GeneratedFilesPanel.Visible = "on"; 
            app.GeneratedFilesPanel.Enable = "on";
            app.FilenameTextAreaLabel.Enable = 'off'; 
            app.FilenameTextArea.Value = ""; 
            app.FilenameTextArea.Visible = 'off';
            app.PreviewGcodeLabel.Enable = 'off'; 
            app.GcodePreviewTextArea.Value = ""; 
            app.GcodePreviewTextArea.Visible = 'off';
            app.PreviewScaffoldLabel.Enable = 'off'; 
            app.UIAxes.Visible = 'off'; 
            cla(app.UIAxes); 
            app.ClicktoenlargeLabel_3.Enable = 'off'; 
            app.ClicktoenlargeLabel_3.Visible = 'off';
        end

        % SET UP ERROR BAR
        function showErrorDialog(~, errorMessage)
            errorDialog = uifigure('Name', 'Error', 'Position', [200, 200, 450, 150], 'Resize', 'off', 'CloseRequestFcn', @(src, event)delete(errorDialog));
            errorLabel = uilabel(errorDialog, 'Text', errorMessage, 'Position', [45, 75, 360, 60], 'FontSize', 16, 'HorizontalAlignment', 'center','WordWrap','on');
            closeButton = uibutton(errorDialog, 'Text', 'OK', 'Position', [165, 20, 120, 30], 'ButtonPushedFcn', @(src, event)delete(errorDialog));
            uiwait(errorDialog);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GET SCREEN SIZE
            screenSize = get(groot, 'ScreenSize');

            % CALCULATE 85% WIDTH AND HEIGHT
            targetWidth = 0.85 * screenSize(3);
            targetHeight = 0.85 * screenSize(4);

            % APP ASPECT RATIO
            aspectRatio = 1.75;

            % MAX DIMENSIONS TO MAINTAIN RATIO
            if targetWidth / aspectRatio <= targetHeight
                appWidth = targetWidth;
                appHeight = targetWidth / aspectRatio;
            else
                appHeight = targetHeight;
                appWidth = targetHeight * aspectRatio;
            end
            appWidth = min(appWidth, screenSize(3));
            appHeight = min(appHeight, screenSize(4));

            % CENTER APP ON SCREEN
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;

            % CREATE APP FIGURE
            app.UIFigure.Position = [appX, appY, 1312, 750];
            %app.UIFigure.Position = [appX, appY, appWidth, appHeight];

            % AUTO FIND DIRECTORY AND ADD SUBFOLDERS
            pwd; app.pathDirectory = convertCharsToStrings(pwd);
            addpath(genpath(app.pathDirectory));
            app.saveDirectory = fullfile(app.pathDirectory, 'UserExports');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetSIpanel(app); 
            resetCDpanel(app); 
            resetPOpanel(app); 
            resetCUpanel(app); 
            resetGFpanel(app); 
            close all;
        end

        % Value changed function: CategoryDropDown
        function CategoryDropDownValueChanged(app, event)

            % ASSIGN USER INPUT TO APP VARIABLE
            app.Category = app.CategoryDropDown.Value;

            if app.Category == "Select"
                resetSIpanel(app)
                resetCDpanel(app)
            elseif app.Category ~= "Select"
                resetCDpanel(app)
                app.GeometryDropDownLabel.Enable = 'on'; 
                app.GeometryDropDown.Enable = 'on';
                app.LengthmmEditField.Enable = 'on'; 
                app.LengthmmEditFieldLabel.Enable = 'on';
                app.LayersEditField.Enable = 'on'; 
                app.LayersEditFieldLabel.Enable = 'on';
                app.SpeedmmminEditField.Enable = 'on'; 
                app.SpeedmmminEditFieldLabel.Enable = 'on';
                app.CommandsaddedTextArea.Enable = "on"; 
                app.CommandsaddedTextArea_2.Enable = "on";

                % SWITCH OPTIONS DEPENDING ON USER CHOICE OF PLANAR OR TUBULAR
                if app.Category == "Planar"
                    app.GeometryDropDown.Items = {'Select', 'P01', 'P02', 'P03', 'P04', 'P05', 'P06'};
                    app.WidthmmEditField.Enable = 'on'; 
                    app.WidthmmEditFieldLabel.Enable = 'on';
                    app.TailmmSlider.Enable = 'on'; 
                    app.TailmmSliderLabel.Enable = 'on';
                    app.DiametermmEditField.Enable = 'off'; 
                    app.DiametermmEditFieldLabel.Enable = 'off'; 
                    app.DiametermmEditField.Value = 0;
                    app.RotationResolutionEditField.Enable = 'off'; 
                    app.RotationResolutionEditFieldLabel.Enable = 'off'; 
                    app.RotationResolutionEditField.Value = 0; 
                    app.MandrelPreferencesLabel.Enable = 'off';
                elseif app.Category == "Tubular"
                    app.GeometryDropDown.Items = {'Select', 'T01', 'T02', 'T03', 'T04', 'T05','T06','T07'};
                    app.WidthmmEditField.Enable = 'off'; 
                    app.WidthmmEditFieldLabel.Enable = 'off'; 
                    app.WidthmmEditField.Value = 0;
                    app.TailmmSlider.Enable = 'off'; 
                    app.TailmmSliderLabel.Enable = 'off';
                    app.DiametermmEditField.Enable = 'on'; 
                    app.DiametermmEditFieldLabel.Enable = 'on';
                    app.RotationResolutionEditField.Enable = 'on'; 
                    app.RotationResolutionEditFieldLabel.Enable = 'on'; 
                    app.MandrelPreferencesLabel.Enable = 'on';
                end
            end
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)

            % ASSIGN USER INPUT TO APP VARIABLE
            app.Geometry = app.GeometryDropDown.Value;

            if app.Geometry == "Select"
                resetCDpanel(app)
            else
                app.ChooseabuildforyourcellLabel.Enable = 'on'; 
                app.ClicktoenlargeLabel.Enable = 'on'; 
                app.ClicktoenlargeLabel.Visible = 'on';
                app.BuildDropDown.Enable = 'on'; 
                app.BuildDropDownLabel.Enable = 'on';

                % SWITCH BASED ON USER PREFERENCE
                switch app.Geometry
                    case "P01"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\P01.png';
                    case "P02"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p'}; app.ImageCellDesign.ImageSource = '.\Figures\P02.png';
                    case "P03"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p'}; app.ImageCellDesign.ImageSource = '.\Figures\P03.png';
                    case "P04"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p'}; app.ImageCellDesign.ImageSource = '.\Figures\P04.png';
                    case "P05"
                        app.BuildDropDown.Items = {'Select','p & q','K & q','P & q'}; app.ImageCellDesign.ImageSource = '.\Figures\P05.png';
                    case "P06"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p'}; app.ImageCellDesign.ImageSource = '.\Figures\P06.png';
                    case "T01"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T01.png';
                    case "T02"
                        app.BuildDropDown.Items = {'Select','p & q','K & q','A & q','P & q'}; app.ImageCellDesign.ImageSource = '.\Figures\T02.png';
                    case "T03"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T03.png';
                    case "T04"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T04.png';
                    case "T05"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T05.png';
                    case "T06"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','A & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T06.png';
                    case "T07"
                        app.BuildDropDown.Items = {'Select','p & q','K & p','P & p'}; app.ImageCellDesign.ImageSource = '.\Figures\T07.png';
                end
                app.ImageCellDesign.Enable = 'on'; app.ImageCellDesign.Visible = 'on';
            end
        end

        % Value changed function: BuildDropDown
        function BuildDropDownValueChanged(app, event)

            % ASSIGN USER INPUT TO APP VARIABLE
            value = app.BuildDropDown.Value;
            app.CellParameter1.Enable = 'on'; 
            app.CellParameter2.Enable = 'on';
            app.ClicktoenlargeLabel.Enable = 'on'; 
            app.ImageCellDesign.Enable = 'on';
            app.Label.Visible = "on"; 
            app.Label.Enable = "on"; 
            app.Label_2.Visible = "on"; 
            app.Label_2.Enable = "on";

            % SWITCH DISPLAY BASED ON USER PREFERENCE
            switch value
                case "p & q"
                    app.Label.Text = "p (um)"; app.Label_2.Text = "q (um)";
                case "K & p"
                    app.Label.Text = "K (mm^2)"; app.Label_2.Text = "p (um)";
                case "K & q"
                    app.Label.Text = "K (mm^2)"; app.Label_2.Text = "q (um)";
                case "A & p"
                    app.Label.Text = "A (deg)"; app.Label_2.Text = "p (um)";
                case "A & q"
                    app.Label.Text = "A (deg)"; app.Label_2.Text = "q (um)";
                case "P & p"
                    app.Label.Text = "P (mm)"; app.Label_2.Text = "p (um)";
                case "P & q"
                    app.Label.Text = "P (mm)"; app.Label_2.Text = "q (um)";
                otherwise
                    app.CellParameter1.Enable = 'off'; 
                    app.CellParameter2.Enable = 'off';
                    app.Label.Visible = "off"; 
                    app.Label.Enable = "off";
                    app.Label_2.Visible = "off"; 
                    app.Label_2.Enable = "off";
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            resetCUpanel(app); 
            resetGFpanel(app);
            
            % ASSIGN USER INPUT TO APP VARIABLE
            close all
            app.Category = app.CategoryDropDown.Value; 
            app.Geometry = app.GeometryDropDown.Value; 
            app.Build = app.BuildDropDown.Value; 
            app.Resolution = app.RotationResolutionEditField.Value;
            app.User_Length = app.LengthmmEditField.Value; 
            app.Layers = app.LayersEditField.Value; 
            app.Speed = app.SpeedmmminEditField.Value;
            app.Param1 = app.CellParameter1.Value; 
            app.Param2 = app.CellParameter2.Value; 
            app.Tail = app.TailmmSlider.Value;
            app.User_Width = app.WidthmmEditField.Value; 
            app.Diameter = app.DiametermmEditField.Value;

            % WAITBAR 1: CHECK USER INPUT
            f = waitbar(0, "Checking user input");
            pause(0.5)

            % CHECK FOR MISSING USER INPUT
            if app.User_Length == 0 || app.Layers == 0 || app.Speed == 0 || app.Param1 == 0 || app.Param2 == 0
                errorMessage = 'Please ensure that construct length, layers, print speed, and build options are filled';
                app.showErrorDialog(errorMessage);
            elseif (app.Category == "Planar" && (app.Tail == 0 || app.User_Width == 0)) || (app.Category == "Tubular" && app.Diameter == 0)
                errorMessage = 'Please fill in all required fields for the selected category';
                app.showErrorDialog(errorMessage);
            else

                % WAITBAR 2: BUILD GEOMETRY
                waitbar(0.25, f, "Building geometry");
                pause(0.5)

                %% BUILD GEOMETRY
                [Information, PreviewKP, PreviewPC, divp, divq, La] = func_GeometrySetup(app.Category, app.Geometry, app.Build, app.Param1, app.Param2, app.Diameter, app.User_Length, app.User_Width, app.saveDirectory);
                
                % ASSIGN TO APP VARIABLES
                app.Generated_Information = Information; 
                app.Generated_PreviewKP = PreviewKP; 
                app.Generated_PreviewPC = PreviewPC;
                app.geo_divp = divp; 
                app.geo_divq = divq; 
                app.geo_La = La;

                % WAITBAR 3: ASSIGN IN ALL IMAGES AND GEOMETRIC PARAMETERS
                waitbar(0.75, f, "Generating images");
                pause(0.5)

                % ASSIGN INTERACTIVE DISPLAYS TO APP AXES
                [ax1, ax2, ax3, ax4] = func_Images(app.Generated_PreviewKP, app.Generated_PreviewPC, app.geo_divp, app.geo_divq, app.geo_La, app.Generated_Information, app.Category, app.Diameter, app.saveDirectory);
                cla(app.UIAxes1); 
                cla(app.UIAxes2); 
                cla(app.UIAxes3); 
                cla(app.UIAxes4);
                userAxes = [ax1, ax2, ax3, ax4];
                uiAxesHandles = [app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4];
                for i = 1:4           
                    copyobj(get(userAxes(i), 'Children'), uiAxesHandles(i));                        % Copy children from user axes to UI axes                    
                    axis(uiAxesHandles(i), axis(userAxes(i)));                                      % Copy axis limits from user axes to UI axes                   
                    title(uiAxesHandles(i), userAxes(i).Title.String, 'Parent', uiAxesHandles(i));  % Copy title, xlabel, ylabel from user axes to UI axes
                    xlabel(uiAxesHandles(i), userAxes(i).XLabel.String);
                    ylabel(uiAxesHandles(i), userAxes(i).YLabel.String);              
                    grid(uiAxesHandles(i), 'on');
                    axis(uiAxesHandles(i), 'equal');
                end
                app.UIAxes1.Visible = 'on'; 
                app.UIAxes2.Visible = 'on'; 
                app.UIAxes3.Visible = 'on'; 
                app.UIAxes4.Visible = 'on'; 

                % ASSIGN GEOMETRY DATA TO APP TABLE
                app.UITable.Data = app.Generated_Information; 
                app.UITable.Enable = 'on';

                % WAITBAR 4: WRAPPING UP
                waitbar(1.0, f, "Cleaning things up");

                % ENABLE GEOMETRY OPTIONS
                if app.Category == "Planar"
                    app.NochangeButton.Enable = 'on'; 
                    app.ScaletosizeButton.Enable = 'on'; 
                    app.FittosizeButton.Enable = 'on';
                elseif app.Category == "Tubular"
                    app.NochangeButton.Enable = 'off'; 
                    app.ScaletosizeButton.Enable = 'on'; 
                    app.FittosizeButton.Enable = 'on';
                end
                app.LayerShiftingandOffsetOptionsLabel.Enable = 'on'; 
                app.NodeCorrectionLabel.Enable = 'on'; 
                app.Switch.Enable = 'on'; 
                app.ExportGcodeButton.Enable = 'on';
            end

            % RETRIEVE ANY USER G-CODE 
            [app.numStartCommands ,app.startCommands] = combineUserCommands(app.CommandsaddedTextArea_2.Value);
            [app.numEndCommands ,app.endCommands] = combineUserCommands(app.CommandsaddedTextArea.Value);

            % CLOSE WAITBAR
            pause(0.5)
            close(f)
        end

        % Button pushed function: NochangeButton
        function NochangeButtonPushed(app, event)
            app.NochangeButton.BackgroundColor = [0.00,0.45,0.74];
            app.ScaletosizeButton.BackgroundColor = [0.5,0.5,0.5];
            app.FittosizeButton.BackgroundColor = [0.5,0.5,0.5];

            app.Length = app.UITable.Data(1,2); 
            app.Width = app.UITable.Data(2,2);
            app.p = app.UITable.Data(3,2); 
            app.q = app.UITable.Data(4,2);
            app.K = app.UITable.Data(5,2); 
            app.P = app.UITable.Data(6,2);
            app.A = app.UITable.Data(7,2); 
            app.B = app.UITable.Data(8,2);
            app.ucP = app.UITable.Data(9,2); 
            app.ucQ = app.UITable.Data(10,2);
            app.ExportGcodeButton.Enable = 'on'; 
            app.pressedButton1 = true;
        end

        % Button pushed function: ScaletosizeButton
        function ScaletosizeButtonPushed(app, event)
            app.NochangeButton.BackgroundColor = [0.5,0.5,0.5];
            app.ScaletosizeButton.BackgroundColor = [0.49,0.18,0.56];
            app.FittosizeButton.BackgroundColor = [0.5,0.5,0.5];

            app.Length = app.UITable.Data(1,3); 
            app.Width = app.UITable.Data(2,3);
            app.p = app.UITable.Data(3,3); 
            app.q = app.UITable.Data(4,3);
            app.K = app.UITable.Data(5,3); 
            app.P = app.UITable.Data(6,3);
            app.A = app.UITable.Data(7,3); 
            app.B = app.UITable.Data(8,3);
            app.ucP = app.UITable.Data(9,3); 
            app.ucQ = app.UITable.Data(10,3);
            app.ExportGcodeButton.Enable = 'on'; 
            app.pressedButton2 = true;
        end

        % Button pushed function: FittosizeButton
        function FittosizeButtonPushed(app, event)
            app.NochangeButton.BackgroundColor = [0.5,0.5,0.5];
            app.ScaletosizeButton.BackgroundColor = [0.5,0.5,0.5];
            app.FittosizeButton.BackgroundColor = [1.00,0.07,0.65];

            app.Length = app.UITable.Data(1,4); 
            app.Width = app.UITable.Data(2,4);
            app.p = app.UITable.Data(3,4); 
            app.q = app.UITable.Data(4,4);
            app.K = app.UITable.Data(5,4); 
            app.P = app.UITable.Data(6,4);
            app.A = app.UITable.Data(7,4); 
            app.B = app.UITable.Data(8,4);
            app.ucP = app.UITable.Data(9,4); 
            app.ucQ = app.UITable.Data(10,4);
            app.ExportGcodeButton.Enable = 'on'; 
            app.pressedButton3 = true;
        end

        % Button pushed function: ExportGcodeButton
        function ExportGcodeButtonPushed(app, event)
            cla(app.UIAxes);
            close all

            % WAITBAR 1: CHECK USER INPUT
            f = waitbar(0,"Checking user input");
            pause(0.5)

            % ASSIGN USER INPUT TO APP VARIABLES
            app.amp = app.AmplitudeumEditField.Value; 
            app.pha = app.PhaseumEditField.Value; 
            app.Mode = app.LayerstoShiftDropDown.Value; 
            app.Correction = app.Switch.Value;

            %% CORRECTION CONDITIONS
            % if (value == "First layer only" || value == "All layers") && (app.Geometry == "T02" || app.Geometry == "T04" || app.Geometry == "T05" || app.Geometry == "T06") 
            %     errorMessage = 'Layer shifting for this design is only available on the second layer onwards. Check back later.';
            %     app.showErrorDialog(errorMessage);
            % else
            % end          

            % CHECK FOR ERRORS
            if app.pressedButton1 == false && app.pressedButton2 == false && app.pressedButton3 == false
                errorMessage = 'Please choose a customisation option: no change (planar only), scale to size or fit to size';
                app.showErrorDialog(errorMessage);
            elseif app.Correction == "On" && app.Mode == "Select"
                errorMessage = 'Please choose a layer shifting mode';
                app.showErrorDialog(errorMessage);
            elseif app.Correction == "On" && (app.amp == 0 && app.pha == 0)
                errorMessage = 'Please provide a value for layer shifting and/or amplitude correction';
                app.showErrorDialog(errorMessage);
            else
                % WAITBAR 2: BUILD THE G-CODE
                waitbar(0.1,f,"Building g-code")
                pause(0.5)

                %% BUILD G-CODE
                File = func_FileSetup(app.startCommands,app.endCommands,app.Mode,app.Category,app.Geometry,app.Diameter,app.Layers,app.Tail,app.Speed,app.amp,app.pha,app.Correction,app.Resolution, ...
                    app.Length,app.Width,app.p,app.q,app.K,app.P,app.A,app.B,app.ucP,app.ucQ,app.saveDirectory);

                % SEND G-CODE TO APP VARIABLE
                app.Generated_File = File; 
                
                % SWITCH DESIGN SCRIPT IF FLAT OR TUBULAR AND GENERATE PREVIEWS
                if app.Category == "Tubular"
                    [fileContent, AxesHandle] = func_TubeViewer(app.Generated_File);
                elseif app.Category == "Planar"
                    [fileContent, AxesHandle] = func_FlatViewer(app.Generated_File);
                end

                % WAITBAR 3: GENERATE PREVIEWS
                waitbar(0.6,f,"Generating scaffold preview")
                pause(0.5)

                % ASSIGN INTERACTIVE DISPLAYS TO APP AXES
                app.User_imgScaffold = AxesHandle;
                copyobj(get(app.User_imgScaffold,'children'), app.UIAxes);
                axis(app.UIAxes, axis(app.User_imgScaffold));
                app.User_imgScaffold.HitTest = 'off';
                app.UIAxes.HitTest = 'on';
                if app.Category == "Tubular"
                    view(app.UIAxes,40,30)
                elseif app.Category == "Planar"
                    view(app.UIAxes,0,90)
                end
                
                % DISPLAY G-CODE IN TEXT AREA
                app.FilenameTextArea.Value = app.Generated_File;
                app.GcodePreviewTextArea.Value = [fileContent{:}];

                % WAITBAR 4: WRAPPING UP
                waitbar(1,f,"Wrapping up")
                pause(0.5)

                app.PreviewScaffoldLabel.Enable = 'on'; 
                app.UIAxes.Visible = 'on'; 
                app.ClicktoenlargeLabel_3.Enable = 'on'; 
                app.ClicktoenlargeLabel_3.Visible = 'on';
                app.FilenameTextArea.Enable = 'on'; 
                app.FilenameTextAreaLabel.Enable = 'on'; 
                app.FilenameTextArea.Visible = 'on';
                app.PreviewGcodeLabel.Enable = 'on'; 
                app.GcodePreviewTextArea.Enable = 'on'; 
                app.GcodePreviewTextArea.Visible = 'on';
                close(f)
                close all;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                app.LayerstoShiftDropDown.Enable = 'on'; 
                app.LayerstoShiftDropDownLabel.Enable = 'on';
                app.AmplitudeumEditField.Enable = "on"; 
                app.AmplitudeumEditFieldLabel.Enable = "on";
                app.PhaseumEditField.Enable = "on"; 
                app.PhaseumEditFieldLabel.Enable = "on";
                app.CorrImage.Enable = 'on'; 
                app.CorrImage.Visible = 'on'; 
                app.ClicktoenlargeLabel_2.Enable = 'on'; 
                app.ClicktoenlargeLabel_2.Visible = 'on';
            else
                app.LayerstoShiftDropDown.Enable = 'off'; 
                app.LayerstoShiftDropDownLabel.Enable = 'off'; 
                app.LayerstoShiftDropDown.Value = "Select";
                app.AmplitudeumEditField.Enable = "off"; 
                app.AmplitudeumEditFieldLabel.Enable = "off";
                app.AmplitudeumEditField.Value = 0; 
                app.PhaseumEditField.Value = 0;
                app.PhaseumEditField.Enable = "off"; 
                app.PhaseumEditFieldLabel.Enable = "off";
                app.CorrImage.Enable = 'off'; 
                app.CorrImage.Visible = 'off';
                app.ClicktoenlargeLabel_2.Enable = 'off'; 
                app.ClicktoenlargeLabel_2.Visible = 'off';
            end

            if value == "On" && (app.Geometry == "P01" || app.Geometry == "T01")
                errorMessage = 'Layer shifting is ineffective on crosshatch (P01) and diamond (T01) geometries.';
                app.showErrorDialog(errorMessage);
                app.Switch.Value = "off";
            else
            end

            %% NO LAYER SHIFTING ON P05
            if value == "On" && app.Geometry == "P05"
                errorMessage = 'Layer shifting is not available on P05 yet. Check back later.';
                app.showErrorDialog(errorMessage);
            else
            end
        end

        % Image clicked function: ImageCellDesign
        function ImageCellDesignClicked(app, event)
            figure(1); 
            imshow(app.ImageCellDesign.ImageSource)
        end

        % Image clicked function: CorrImage
        function CorrImageClicked(app, event)
            figure(7); 
            imshow(app.CorrImage.ImageSource);
        end

        % Button down function: UIAxes, UIAxes1, UIAxes2, UIAxes3, UIAxes4
        function UIAxesButtonDown(app, event)
            
            % GET ALL IMAGE DATA
            sourceAxes = event.Source;
            newFigure = figure; 
            newAxes = copyobj(sourceAxes, newFigure);
            newAxes.Units = 'normalized'; 
            newAxes.Position = [0, 0, 1, 1]; 
            newAxes.DataAspectRatio = [1, 1, 1]; 
            newAxes.FontSize = app.UIAxes.FontSize*1.5;
        end

        % Value changed function: LayerstoShiftDropDown
        function LayerstoShiftDropDownValueChanged(app, event)
            value = app.LayerstoShiftDropDown.Value;

            %% CONDITIONAL LAYER SHIFTING FOR SOME GEOMETRIES
            if (value == "First layer only" || value == "All layers") && (app.Geometry == "T02" || app.Geometry == "T04" || app.Geometry == "T05" || app.Geometry == "T06") 
                errorMessage = 'Layer shifting for this design is only available on the second layer onwards. Check back later.';
                app.showErrorDialog(errorMessage);
            else
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {750, 750, 750};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {750, 750};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {451, '1x', 365};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ScaffoldInformationPanel = uipanel(app.LeftPanel);
            app.ScaffoldInformationPanel.Title = 'Scaffold Information';
            app.CategoryDropDownLabel = uilabel(app.ScaffoldInformationPanel);
            app.CategoryDropDownLabel.Text = 'Category';
            app.CategoryDropDown = uidropdown(app.ScaffoldInformationPanel);
            app.CategoryDropDown.Items = {'Select', 'Planar', 'Tubular'};
            app.CategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @CategoryDropDownValueChanged, true);
            app.CategoryDropDown.Tooltip = {'Select a MEW scaffold category: planar (scaffolds printed on a flat collector) or tubular (scaffolds printed on a rotating mandrel collector)'};
            app.CategoryDropDown.Value = 'Select';
            app.GeometryDropDownLabel = uilabel(app.ScaffoldInformationPanel);
            app.GeometryDropDownLabel.Enable = 'off';
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.ScaffoldInformationPanel);
            app.GeometryDropDown.Items = {'Select'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Enable = 'off';
            app.GeometryDropDown.Tooltip = {'Select a geometry. A preview of the repeating cell will display in the cell design panel.'};
            app.GeometryDropDown.Value = 'Select';
            app.WidthmmEditFieldLabel = uilabel(app.ScaffoldInformationPanel);
            app.WidthmmEditFieldLabel.Enable = 'off';
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.LengthmmEditField = uieditfield(app.ScaffoldInformationPanel, 'numeric');
            app.LengthmmEditField.Enable = 'off';
            app.LengthmmEditField.Tooltip = {'Choose the desired scaffold length in millimeters.'};
            app.WidthmmEditField = uieditfield(app.ScaffoldInformationPanel, 'numeric');
            app.WidthmmEditField.Enable = 'off';
            app.WidthmmEditField.Tooltip = {'Choose the desired scaffold width in millimeters (planar scaffolds only)'};
            app.LengthmmEditFieldLabel = uilabel(app.ScaffoldInformationPanel);
            app.LengthmmEditFieldLabel.Enable = 'off';
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LayersEditFieldLabel = uilabel(app.ScaffoldInformationPanel);
            app.LayersEditFieldLabel.Enable = 'off';
            app.LayersEditFieldLabel.Text = 'Layers';
            app.LayersEditField = uieditfield(app.ScaffoldInformationPanel, 'numeric');
            app.LayersEditField.Enable = 'off';
            app.LayersEditField.Tooltip = {'Choose the number of scaffold layers.'};
            app.TailmmSliderLabel = uilabel(app.ScaffoldInformationPanel);
            app.TailmmSliderLabel.Enable = 'off';
            app.TailmmSliderLabel.Text = 'Tail (mm)';
            app.TailmmSlider = uislider(app.ScaffoldInformationPanel);
            app.TailmmSlider.Limits = [0 5];
            app.TailmmSlider.Enable = 'off';
            app.TailmmSlider.Tooltip = {'Choose the size of the scaffold tail (planar scaffolds only: this is an extra region where the fiber can turn around)'};
            app.TailmmSlider.Value = 0.5;
            app.SpeedmmminEditFieldLabel = uilabel(app.ScaffoldInformationPanel);
            app.SpeedmmminEditFieldLabel.Enable = 'off';
            app.SpeedmmminEditFieldLabel.Text = 'Speed (mm/min)';
            app.SpeedmmminEditField = uieditfield(app.ScaffoldInformationPanel, 'numeric');
            app.SpeedmmminEditField.Enable = 'off';
            app.SpeedmmminEditField.Tooltip = {'Choose the desired print speed. At QUT, the maximum print speed is 800 mm/min.'};
            app.CellDesignPanel = uipanel(app.LeftPanel);
            app.CellDesignPanel.Title = 'Cell Design';
            app.BuildDropDown = uidropdown(app.CellDesignPanel);
            app.BuildDropDown.Items = {'Select'};
            app.BuildDropDown.ValueChangedFcn = createCallbackFcn(app, @BuildDropDownValueChanged, true);
            app.BuildDropDown.Enable = 'off';
            app.BuildDropDown.Tooltip = {'Select the parameters of interest to build the repeating cell. Click on the cell image for a breakdown of parameters. '};
            app.BuildDropDown.Value = 'Select';
            app.ImageCellDesign = uiimage(app.CellDesignPanel);
            app.ImageCellDesign.ImageClickedFcn = createCallbackFcn(app, @ImageCellDesignClicked, true);
            app.ImageCellDesign.Enable = 'off';
            app.ImageCellDesign.Visible = 'off';
            app.BuildDropDownLabel = uilabel(app.CellDesignPanel);
            app.BuildDropDownLabel.Enable = 'off';
            app.BuildDropDownLabel.Text = 'Build';
            app.ClicktoenlargeLabel = uilabel(app.CellDesignPanel);
            app.ClicktoenlargeLabel.Enable = 'off';
            app.ClicktoenlargeLabel.Visible = 'off';
            app.ClicktoenlargeLabel.Text = 'Click to enlarge';
            app.ChooseabuildforyourcellLabel = uilabel(app.CellDesignPanel);
            app.ChooseabuildforyourcellLabel.Enable = 'off';
            app.ChooseabuildforyourcellLabel.Text = 'Choose a build for your cell';
            app.Label = uilabel(app.CellDesignPanel);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '1';
            app.CellParameter1 = uieditfield(app.CellDesignPanel, 'numeric');
            app.CellParameter1.Enable = 'off';
            app.Label_2 = uilabel(app.CellDesignPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '2';
            app.CellParameter2 = uieditfield(app.CellDesignPanel, 'numeric');
            app.CellParameter2.Enable = 'off';
            app.PrintOptionsPanel = uipanel(app.LeftPanel);
            app.PrintOptionsPanel.Title = 'Print Options';
            app.MandrelPreferencesLabel = uilabel(app.PrintOptionsPanel);
            app.MandrelPreferencesLabel.Enable = 'off';
            app.MandrelPreferencesLabel.Text = 'Mandrel Preferences';
            app.DiametermmEditField = uieditfield(app.PrintOptionsPanel, 'numeric');
            app.DiametermmEditField.Enable = 'off';
            app.DiametermmEditField.Tooltip = {'Choose the diameter of the rotating mandrel collector (tubular scaffolds only)'};
            app.DiametermmEditFieldLabel = uilabel(app.PrintOptionsPanel);
            app.DiametermmEditFieldLabel.Enable = 'off';
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.RotationResolutionEditField = uieditfield(app.PrintOptionsPanel, 'numeric');
            app.RotationResolutionEditField.Enable = 'off';
            app.RotationResolutionEditField.Tooltip = {'This factor is unique to each MEW printer and users will need to input this factor. It ensures the commands to turn the mandrel motor moves the surface of the mandrel by the desired mm. So this adjusts for the pulley gear ratio and mandrel diameter. If left blank, the app will use the inbuilt calculations for the QUT MEW printers. '};
            app.RotationResolutionEditFieldLabel = uilabel(app.PrintOptionsPanel);
            app.RotationResolutionEditFieldLabel.Enable = 'off';
            app.RotationResolutionEditFieldLabel.Text = 'Rotation Resolution';
            app.CommandsaddedTextArea = uitextarea(app.PrintOptionsPanel);
            app.CommandsaddedTextArea.Enable = 'off';
            app.PersonalisedGcodeCommandsEditFieldLabel_2 = uilabel(app.PrintOptionsPanel);
            app.PersonalisedGcodeCommandsEditFieldLabel_2.Text = 'Personalised Gcode Commands';
            app.CommandsaddedTextArea_2 = uitextarea(app.PrintOptionsPanel);
            app.CommandsaddedTextArea_2.Enable = 'off';
            app.AddcommandstobeexecutedpriortotheprintstartLabel = uilabel(app.PrintOptionsPanel);
            app.AddcommandstobeexecutedpriortotheprintstartLabel.Text = 'Add commands to be executed prior to the print start';
            app.AddcommandstobeexecutedaftertheprintisfinishedLabel = uilabel(app.PrintOptionsPanel);
            app.AddcommandstobeexecutedaftertheprintisfinishedLabel.Text = 'Add commands to be executed after the print is finished';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.MeltElectroCoderLabel = uilabel(app.LeftPanel);
            app.MeltElectroCoderLabel.Text = 'Melt ElectroCoder';
            app.NomenclatureLabel_4 = uilabel(app.LeftPanel);
            app.NomenclatureLabel_4.Text = 'Designed by                          &';
            app.Hyperlink = uihyperlink(app.LeftPanel);
            app.Hyperlink.URL = 'https://research.qut.edu.au/cbt/people/brenna-devlin/';
            app.Hyperlink.Text = 'Brenna Devlin';
            app.Hyperlink2 = uihyperlink(app.LeftPanel);
            app.Hyperlink2.URL = 'https://au.linkedin.com/in/shahak-kuba-36a8b21a7';
            app.Hyperlink2.Text = 'Shahak Kuba';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CustomisationPanel = uipanel(app.CenterPanel);
            app.CustomisationPanel.Title = 'Customisation';
            app.TabGroup = uitabgroup(app.CustomisationPanel);
            app.TabGroup.Tooltip = {'These tabs displays a visual breakdown of the repeating cell. In the event that the repeating cell does not perfectly fit in the scaffold bounds, these tabs and the table below allows users to decide which combination of parameters best suits their application.'};
            app.ScaleTab = uitab(app.TabGroup);
            app.ScaleTab.Title = 'Scaled';
            app.UIAxes1 = uiaxes(app.ScaleTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes1.Visible = 'off';
            app.UIAxes3 = uiaxes(app.ScaleTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes3.Visible = 'off';
            app.FittedTab = uitab(app.TabGroup);
            app.FittedTab.Title = 'Fitted';
            app.UIAxes2 = uiaxes(app.FittedTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2.Visible = 'off';
            app.UIAxes4 = uiaxes(app.FittedTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes4.Visible = 'off';
            app.UITable = uitable(app.CustomisationPanel);
            app.UITable.ColumnName = {'Variable'; 'No Change'; 'Scaled'; 'Fitted'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'This table displays a breakdown of scaffold and repeating cell parameters. In the event that the repeating cell does not perfectly fit in the scaffold bounds, this table allows users to decide which combination of parameters best suits their application.'};
            app.UITable.Enable = 'off';
            app.NochangeButton = uibutton(app.CustomisationPanel, 'push');
            app.NochangeButton.ButtonPushedFcn = createCallbackFcn(app, @NochangeButtonPushed, true);
            app.NochangeButton.Enable = 'off';
            app.NochangeButton.Text = 'No change';
            app.ScaletosizeButton = uibutton(app.CustomisationPanel, 'push');
            app.ScaletosizeButton.ButtonPushedFcn = createCallbackFcn(app, @ScaletosizeButtonPushed, true);
            app.ScaletosizeButton.Enable = 'off';
            app.ScaletosizeButton.Text = 'Scale to size';
            app.FittosizeButton = uibutton(app.CustomisationPanel, 'push');
            app.FittosizeButton.ButtonPushedFcn = createCallbackFcn(app, @FittosizeButtonPushed, true);
            app.FittosizeButton.Enable = 'off';
            app.FittosizeButton.Text = 'Fit to size';
            app.NodeCorrectionLabel = uilabel(app.CustomisationPanel);
            app.NodeCorrectionLabel.Enable = 'off';
            app.NodeCorrectionLabel.Text = 'Node Correction';
            app.Switch = uiswitch(app.CustomisationPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.AmplitudeumEditFieldLabel = uilabel(app.CustomisationPanel);
            app.AmplitudeumEditFieldLabel.Enable = 'off';
            app.AmplitudeumEditFieldLabel.Text = 'Amplitude (um)';
            app.AmplitudeumEditField = uieditfield(app.CustomisationPanel, 'numeric');
            app.AmplitudeumEditField.Enable = 'off';
            app.AmplitudeumEditField.Tooltip = {'Choose your offset in the perpendicular direction of print in micrometers.'};
            app.PhaseumEditFieldLabel = uilabel(app.CustomisationPanel);
            app.PhaseumEditFieldLabel.Enable = 'off';
            app.PhaseumEditFieldLabel.Text = 'Phase (um)';
            app.PhaseumEditField = uieditfield(app.CustomisationPanel, 'numeric');
            app.PhaseumEditField.Enable = 'off';
            app.PhaseumEditField.Tooltip = {'Choose your offset in the direction of print in micrometers.'};
            app.LayerShiftingandOffsetOptionsLabel = uilabel(app.CustomisationPanel);
            app.LayerShiftingandOffsetOptionsLabel.Enable = 'off';
            app.LayerShiftingandOffsetOptionsLabel.Tooltip = {'The following options allow users to apply a universal node offset to counter any effects caused by MEW ''jet lag''. '};
            app.LayerShiftingandOffsetOptionsLabel.Text = 'Layer Shifting and Offset Options';
            app.LayerstoShiftDropDownLabel = uilabel(app.CustomisationPanel);
            app.LayerstoShiftDropDownLabel.Enable = 'off';
            app.LayerstoShiftDropDownLabel.Text = 'Layers to Shift';
            app.LayerstoShiftDropDown = uidropdown(app.CustomisationPanel);
            app.LayerstoShiftDropDown.Items = {'Select', 'First layer only', 'Second layer onwards', 'All layers'};
            app.LayerstoShiftDropDown.ItemsData = {'Select', 'First layer only', 'Second layer onwards', 'All layers'};
            app.LayerstoShiftDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerstoShiftDropDownValueChanged, true);
            app.LayerstoShiftDropDown.Enable = 'off';
            app.LayerstoShiftDropDown.Value = 'Select';
            app.CorrImage = uiimage(app.CustomisationPanel);
            app.CorrImage.ImageClickedFcn = createCallbackFcn(app, @CorrImageClicked, true);
            app.CorrImage.Enable = 'off';
            app.CorrImage.Visible = 'off';
            app.CorrImage.ImageSource = fullfile(pathToMLAPP, 'Figures', 'corr2.png');
            app.ClicktoenlargeLabel_2 = uilabel(app.CustomisationPanel);
            app.ClicktoenlargeLabel_2.Enable = 'off';
            app.ClicktoenlargeLabel_2.Visible = 'off';
            app.ClicktoenlargeLabel_2.Text = 'Click to enlarge';
            app.ExportGcodeButton = uibutton(app.CenterPanel, 'push');
            app.ExportGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGcodeButtonPushed, true);
            app.ExportGcodeButton.Enable = 'off';
            app.ExportGcodeButton.Text = 'Export Gcode';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageBTM = uiimage(app.RightPanel);
            app.ImageBTM.URL = 'https://research.qut.edu.au/biofabrication/';
            app.ImageBTM.ImageSource = fullfile(pathToMLAPP, 'Figures', 'btmlab.png');
            app.ImageQUT = uiimage(app.RightPanel);
            app.ImageQUT.URL = 'https://www.qut.edu.au/';
            app.ImageQUT.ImageSource = fullfile(pathToMLAPP, 'Figures', 'qut.jpg');
            app.GeneratedFilesPanel = uipanel(app.RightPanel);
            app.GeneratedFilesPanel.Title = 'Generated Files';
            app.UIAxes = uiaxes(app.GeneratedFilesPanel);
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Y (mm)')
            zlabel(app.UIAxes, 'Z (mm)')
            app.UIAxes.ZTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.GcodePreviewTextArea = uitextarea(app.GeneratedFilesPanel);
            app.GcodePreviewTextArea.Editable = 'off';
            app.GcodePreviewTextArea.Enable = 'off';
            app.GcodePreviewTextArea.Visible = 'off';
            app.PreviewGcodeLabel = uilabel(app.GeneratedFilesPanel);
            app.PreviewGcodeLabel.Enable = 'off';
            app.PreviewGcodeLabel.Text = 'Preview (Gcode)';
            app.PreviewScaffoldLabel = uilabel(app.GeneratedFilesPanel);
            app.PreviewScaffoldLabel.Enable = 'off';
            app.PreviewScaffoldLabel.Text = 'Preview (Scaffold)';
            app.FilenameTextAreaLabel = uilabel(app.GeneratedFilesPanel);
            app.FilenameTextAreaLabel.Enable = 'off';
            app.FilenameTextAreaLabel.Text = 'Filename';
            app.FilenameTextArea = uitextarea(app.GeneratedFilesPanel);
            app.FilenameTextArea.Editable = 'off';
            app.FilenameTextArea.Enable = 'off';
            app.FilenameTextArea.Visible = 'off';
            app.ClicktoenlargeLabel_3 = uilabel(app.GeneratedFilesPanel);
            app.ClicktoenlargeLabel_3.Enable = 'off';
            app.ClicktoenlargeLabel_3.Visible = 'off';
            app.ClicktoenlargeLabel_3.Text = 'Click to enlarge';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImageUO = uiimage(app.RightPanel);
            app.ImageUO.URL = 'https://dalton.uoregon.edu/';
            app.ImageUO.ImageSource = fullfile(pathToMLAPP, 'Figures', 'daltonlab.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeltElectroCoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end